classdef AudioSampler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_6                 matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        EQUALIZERPanel          matlab.ui.container.Panel
        kHzSlider_3             matlab.ui.control.Slider
        kHzSlider_3Label        matlab.ui.control.Label
        kHzSlider_2             matlab.ui.control.Slider
        kHzSlider_2Label        matlab.ui.control.Label
        kHzSlider               matlab.ui.control.Slider
        kHzSliderLabel          matlab.ui.control.Label
        HzSlider_2              matlab.ui.control.Slider
        HzLabel                 matlab.ui.control.Label
        HzSlider                matlab.ui.control.Slider
        HzSliderLabel           matlab.ui.control.Label
        TONEGENERATORPanel      matlab.ui.container.Panel
        SYNTHLOADDropDown       matlab.ui.control.DropDown
        SYNTHLOADDropDownLabel  matlab.ui.control.Label
        SAVEButton              matlab.ui.control.Button
        GENERATEButton          matlab.ui.control.Button
        DURATIONEditField       matlab.ui.control.NumericEditField
        DURATIONEditFieldLabel  matlab.ui.control.Label
        FREQUENCYEditField      matlab.ui.control.NumericEditField
        FrequencyHzLabel        matlab.ui.control.Label
        WAVEFORMDropDown        matlab.ui.control.DropDown
        WAVEFORMDropDownLabel   matlab.ui.control.Label
        AUDIOPROCESSINGPanel    matlab.ui.container.Panel
        Panel_5                 matlab.ui.container.Panel
        STOPButton              matlab.ui.control.Button
        PAUSEButton             matlab.ui.control.Button
        Panel_4                 matlab.ui.container.Panel
        SAMPLERV10Label         matlab.ui.control.Label
        AUSTINPELLETIERLabel    matlab.ui.control.Label
        VOLUMESlider            matlab.ui.control.Slider
        VOLUMESliderLabel       matlab.ui.control.Label
        DIRECTIONSwitch         matlab.ui.control.Switch
        DIRECTIONSwitchLabel    matlab.ui.control.Label
        SPEEDSlider             matlab.ui.control.Slider
        SPEEDSliderLabel        matlab.ui.control.Label
        Panel_3                 matlab.ui.container.Panel
        REVERBLabel             matlab.ui.control.Label
        INTENSITYSlider         matlab.ui.control.Slider
        INTENSITYLabel          matlab.ui.control.Label
        ReverbSwitch            matlab.ui.control.Switch
        Panel_2                 matlab.ui.container.Panel
        ECHOLabel               matlab.ui.control.Label
        EchoSwitch              matlab.ui.control.Switch
        DELAYTIMEKnob           matlab.ui.control.Knob
        DELAYTIMEKnobLabel      matlab.ui.control.Label
        FEEDBACKKnob            matlab.ui.control.Knob
        FEEDBACKKnobLabel       matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        CHOPLabel               matlab.ui.control.Label
        APPLYButton             matlab.ui.control.Button
        ENDEditField            matlab.ui.control.NumericEditField
        EndLabel                matlab.ui.control.Label
        STARTEditField          matlab.ui.control.NumericEditField
        StartLabel              matlab.ui.control.Label
        SLOTDropDown            matlab.ui.control.DropDown
        SlotLabel               matlab.ui.control.Label
        PADSSAMPLESLOTSPanel    matlab.ui.container.Panel
        LOADButton_9            matlab.ui.control.Button
        LOADButton_8            matlab.ui.control.Button
        LOADButton_7            matlab.ui.control.Button
        LOADButton_6            matlab.ui.control.Button
        LOADButton_5            matlab.ui.control.Button
        LOADButton_4            matlab.ui.control.Button
        LOADButton_3            matlab.ui.control.Button
        LOADButton_2            matlab.ui.control.Button
        LOADButton              matlab.ui.control.Button
        PAD9Button              matlab.ui.control.Button
        PAD8Button              matlab.ui.control.Button
        PAD7Button              matlab.ui.control.Button
        PAD6Button              matlab.ui.control.Button
        PAD5Button              matlab.ui.control.Button
        PAD4Button              matlab.ui.control.Button
        PAD3Button              matlab.ui.control.Button
        PAD2Button              matlab.ui.control.Button
        PAD1Button              matlab.ui.control.Button
    end

    %CGPT used for all color rgbs, pain to find nice color
    % nice retro style font on gui, inspiration from AMIGO sampler, very
    % cool look to it
    % will upload guide along with files, really beginner friendly for
    % people to follow and share with others, 
    % hoping to expand and maybe implement dynamic changing, not sure if
    % possible
    properties (Access = private)
        Samples cell = cell(9,1)       % raw audio per pad (Nx1 or Nx2) 
    FsList double = zeros(9,1)         % sample rate per pad 
    Meta table                         % meets "Tables" requirement 
    Player = []                        % currently playing audio 
    LastTone double = []               % generated synth tone 
    SynthFs double = 44100             % synth sample rate 
    end
    
    methods (Access = private)


    function slot = getSlotFromDropdown(app, dd)
        slot = str2double(dd.Value);
        if isnan(slot) || slot < 1 || slot > 9
            slot = 1;
        end
    end

    function loadSlot(app, slot)
    try
        [f,p] = uigetfile({'*.wav;*.mp3;*.m4a','Audio Files'}, 'Select audio'); % prefer .wav / .mp3, usually easier
        if isequal(f,0); return; end
        full = fullfile(p,f);

        [x,fs] = audioread(full);

        app.Samples{slot} = x;
        app.FsList(slot) = fs;

        app.Meta.File(slot) = string(f);
        app.Meta.Fs(slot) = fs;
        app.Meta.NumSamples(slot) = size(x,1);

       % load buttons and the corresponding color, greenish, this will be
       % displayed when a file is in the load
        loadButtons = [app.LOADButton app.LOADButton_2 app.LOADButton_3 ...
                       app.LOADButton_4 app.LOADButton_5 app.LOADButton_6 ...
                       app.LOADButton_7 app.LOADButton_8 app.LOADButton_9];

        loadButtons(slot).BackgroundColor = [0.3 0.45 0.3];
        

    catch ME
        uialert(app.UIFigure, ME.message, "Load Error");
    end
end

function plotWave(app, ax, y, fs)

    % first channel plotting 
    if size(y,2) > 1
        yPlot = y(:,1);
    else
        yPlot = y;
    end

    % shorter preview window so the user can actually see the waveform,
    % when testing the long drawn out waveforms really just looked like a
    % solid block, 
    if ax == app.UIAxes2
        previewTime = 0.02;   % synth
    else
        previewTime = 0.1;    % waveform
    end

    N = min(length(yPlot), round(previewTime * fs));
    yDisp = yPlot(1:N);
    tDisp = (0:N-1) / fs;

    cla(ax)
     
    plot(ax, tDisp, yDisp, ...
        'Color', [0.56 0.88 0.36], ... 
        'LineWidth', 1)

    xlabel(ax, "Time (s)")
    ylabel(ax, "Amplitude")
    xlim(ax, [0 previewTime])

   
    ylim(ax, 'auto')
end


    function y = applyChop(app, x, fs)
        t0 = app.STARTEditField.Value;
        t1 = app.ENDEditField.Value;

        % invalid chop will do nothing
        if isempty(t0) || isempty(t1) || t1 <= t0
            y = x;
            return;
        end

        i0 = max(1, floor(t0*fs) + 1);
        i1 = min(size(x,1), floor(t1*fs));

        if i1 <= i0
            y = x;
        else
            y = x(i0:i1,:);
        end
    end

   function y = applyEQ(app, y, fs)

    gainsDb = [app.HzSlider.Value, app.HzSlider_2.Value, ...
               app.kHzSlider.Value, app.kHzSlider_2.Value, ...
               app.kHzSlider_3.Value];

    gains = 10.^(gainsDb/20);

    % mono / stereo
    [N, C] = size(y);
    yOut = zeros(size(y));

    for ch = 1:C
        x = y(:,ch);

        Y = fft(x);

        % freq vector
        f = (0:N-1)' * (fs/N);

        gain = ones(N,1);

        % + freq bands, will be mirrored 
        band1 = (f >= 0    & f < 150);
        band2 = (f >= 150  & f < 600);
        band3 = (f >= 600  & f < 2000);
        band4 = (f >= 2000 & f < 6000);
        band5 = (f >= 6000 & f <= fs/2);

        gain(band1) = gains(1);
        gain(band2) = gains(2);
        gain(band3) = gains(3);
        gain(band4) = gains(4);
        gain(band5) = gains(5);

        % mirror from above
        for k = 2:floor(N/2)
            gain(N-k+2) = gain(k);
        end

        Yeq = Y .* gain;
        xeq = real(ifft(Yeq));

        yOut(:,ch) = xeq;
    end

    % normalize
    mx = max(abs(yOut),[],'all');
    if mx > 1
        yOut = yOut / mx;
    end

    y = yOut;
   end

   function y = applyReverb(app, y, fs)

    amount = app.INTENSITYSlider.Value;

    % reverb section, unable to figure out clipping, maybe max..
    delays = [0.012 0.024 0.038 0.052 0.070];   % (s)
    gains  = amount * [0.45 0.30 0.20 0.12 0.08];

    yRev = y;

    for k = 1:length(delays)
       d = round(delays(k) * fs);
       temp = zeros(size(y));
       if size(y,2) == 1
           temp(d+1:end) = y(1:end-d);
       else
           temp(d+1:end,:) = y(1:end-d,:);
       end
       yRev = yRev + gains(k) * temp;
   end

    % normalize for clips
    mx = max(abs(yRev),[],'all');
    if mx > 1
        yRev = yRev / mx;
    end

    y = yRev;
   end


    function y = applyEffects(app, y, fs)
    % simple direction, ap
    if strcmp(app.DIRECTIONSwitch.Value, "Backward")
        y = flipud(y);
    end

    % volume, warning very loud at 2! careful!!!
    y = y * app.VOLUMESlider.Value;

    % speed limit on 0 set to 0.5 remove errors 
    spd = max(0.5, app.SPEEDSlider.Value);
    n = size(y,1);
    xi = (1:n)';
    xo = linspace(1,n, max(2, round(n/spd)))';
    y = interp1(xi, y, xo, "linear", 0);

    % echo test 4, works ! set hard cap 0.95
    if strcmp(app.EchoSwitch.Value, "On")
        d = max(1, round(app.DELAYTIMEKnob.Value * fs));
        fb = min(max(app.FEEDBACKKnob.Value, 0), 0.95);

        y2 = y;
        for i = d+1:size(y2,1)
            y2(i,:) = y2(i,:) + fb * y2(i-d,:);
        end
        y = y2;
    end

    % reverb switch with apply
    if strcmp(app.ReverbSwitch.Value, "On")
        y = app.applyReverb(y, fs);
    end

    % normalize
    mx = max(abs(y),[],'all');
    if mx > 1
        y = y / mx;
    end
end

    function y = processAudio(app, x, fs)
        y = app.applyChop(x, fs);
        y = app.applyEQ(y, fs);
        y = app.applyEffects(y, fs);
    end

    function playSlot(app, slot)
    x = app.Samples{slot};
    if isempty(x)
        uialert(app.UIFigure, "No audio loaded in this slot.", "Play Error");
        return;
    end
    fs = app.FsList(slot);

    y = app.processAudio(x, fs);

    % stop prev if existing
    if ~isempty(app.Player)
        stop(app.Player);
    end

    % pad aray, 
    padButtons = [app.PAD1Button app.PAD2Button app.PAD3Button ...
                  app.PAD4Button app.PAD5Button app.PAD6Button ...
                  app.PAD7Button app.PAD8Button app.PAD9Button];

    % will reset pads to grey RGB color
    for i = 1:9
        padButtons(i).BackgroundColor = [0.18 0.18 0.18];
    end

    % highlight active pad in green ( sage ) for all load/play aesthetic,
    % also useful for users to understand what is active
    padButtons(slot).BackgroundColor = [0.3 0.45 0.3];

    % play
    app.Player = audioplayer(y, fs);
    play(app.Player);

    app.plotWave(app.UIAxes, y, fs);
end

    function generateTone(app)
        fs = app.SynthFs;
        f = app.FREQUENCYEditField.Value;
        dur = app.DURATIONEditField.Value;

        if isempty(f) || f <= 0
            uialert(app.UIFigure, "Frequency must be > 0.", "Synth Error");
            return;
        end
        if isempty(dur) || dur <= 0
            uialert(app.UIFigure, "Duration must be > 0.", "Synth Error");
            return;
        end

        t = (0:1/fs:dur)'; 
        t(end) = [];

        switch app.WAVEFORMDropDown.Value
            case "Sine"
                tone = sin(2*pi*f*t);
            case "Square"
                tone = sign(sin(2*pi*f*t));
            case "Saw"
                tone = 2*(t*f - floor(0.5 + t*f));
            case "AM"
                fm = max(1, f/8);
                tone = (1 + 0.7*sin(2*pi*fm*t)) .* sin(2*pi*f*t);
            otherwise
                tone = sin(2*pi*f*t);
        end

        app.LastTone = 0.8 * tone; % goodish amp
        app.plotWave(app.UIAxes2, app.LastTone, fs);
    end

    function saveToneToSlot(app)
        if isempty(app.LastTone)
            uialert(app.UIFigure, "Generate a tone first.", "Save Error");
            return;
        end
        slot = app.getSlotFromDropdown(app.SYNTHLOADDropDown);

        app.Samples{slot} = app.LastTone;
        app.FsList(slot) = app.SynthFs;

        app.Meta.File(slot) = "SYNTH";
        app.Meta.Fs(slot) = app.SynthFs;
        app.Meta.NumSamples(slot) = numel(app.LastTone);

        % load button update for color
loadButtons = [app.LOADButton app.LOADButton_2 app.LOADButton_3 ...
               app.LOADButton_4 app.LOADButton_5 app.LOADButton_6 ...
               app.LOADButton_7 app.LOADButton_8 app.LOADButton_9];

loadButtons(slot).BackgroundColor = [0.3 0.45 0.3];
    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
             app.Meta = table((1:9)', strings(9,1), zeros(9,1), zeros(9,1), ...
        'VariableNames', ["Slot","File","Fs","NumSamples"]);

    app.SPEEDSlider.Limits = [0.5 2];
    app.SPEEDSlider.Value = 1;

    app.FEEDBACKKnob.Limits = [0 0.95];
    app.FEEDBACKKnob.Value = 0.0;

    app.DELAYTIMEKnob.Limits = [0 2];
    app.DELAYTIMEKnob.Value = 0.0;

    app.VOLUMESlider.Value = 1;

    app.EchoSwitch.Items = {'Off','On'};
    app.EchoSwitch.Value = 'Off';

    app.STARTEditField.Value = 0;
    app.ENDEditField.Value = 0;

    app.ReverbSwitch.Items = {'Off','On'};
    app.ReverbSwitch.Value = 'Off';

    app.INTENSITYSlider.Limits = [0 1];
    app.INTENSITYSlider.Value = 0.0;

   
    padButtons = [app.PAD1Button app.PAD2Button app.PAD3Button ...
                  app.PAD4Button app.PAD5Button app.PAD6Button ...
                  app.PAD7Button app.PAD8Button app.PAD9Button];

    for i = 1:9
        padButtons(i).BackgroundColor = [0.15 0.15 0.15];
    end
        end

        % Button pushed function: PAD1Button
        function PAD1ButtonPushed(app, event)
            app.playSlot(1);
        end

        % Button pushed function: PAD2Button
        function PAD2ButtonPushed(app, event)
            app.playSlot(2);
        end

        % Button pushed function: PAD3Button
        function PAD3ButtonPushed(app, event)
            app.playSlot(3);
        end

        % Button pushed function: PAD4Button
        function PAD4ButtonPushed(app, event)
            app.playSlot(4);
        end

        % Button pushed function: PAD5Button
        function PAD5ButtonPushed(app, event)
            app.playSlot(5);
        end

        % Button pushed function: PAD6Button
        function PAD6ButtonPushed(app, event)
            app.playSlot(6);
        end

        % Button pushed function: PAD7Button
        function PAD7ButtonPushed(app, event)
            app.playSlot(7);
        end

        % Button pushed function: PAD8Button
        function PAD8ButtonPushed(app, event)
            app.playSlot(8);
        end

        % Button pushed function: PAD9Button
        function PAD9ButtonPushed(app, event)
            app.playSlot(9);
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            app.loadSlot(1);
        end

        % Button pushed function: LOADButton_2
        function LOADButton_2Pushed(app, event)
            app.loadSlot(2);
        end

        % Button pushed function: LOADButton_3
        function LOADButton_3Pushed(app, event)
            app.loadSlot(3);
        end

        % Button pushed function: LOADButton_4
        function LOADButton_4Pushed(app, event)
            app.loadSlot(4);
        end

        % Button pushed function: LOADButton_5
        function LOADButton_5Pushed(app, event)
            app.loadSlot(5);
        end

        % Button pushed function: LOADButton_6
        function LOADButton_6Pushed(app, event)
            app.loadSlot(6);
        end

        % Button pushed function: LOADButton_7
        function LOADButton_7Pushed(app, event)
            app.loadSlot(7);
        end

        % Button pushed function: LOADButton_8
        function LOADButton_8Pushed(app, event)
            app.loadSlot(8);
        end

        % Button pushed function: LOADButton_9
        function LOADButton_9Pushed(app, event)
            app.loadSlot(9);
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            app.generateTone();
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.saveToneToSlot();
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            slot = app.getSlotFromDropdown(app.SLOTDropDown);
    app.playSlot(slot);
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            if isempty(app.Player)
        return
    end

    if strcmp(app.Player.Running,'on')
        pause(app.Player)
        app.PAUSEButton.Text = "RESUME"
    else
        resume(app.Player)
        app.PAUSEButton.Text = "PAUSE"
    end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
             if ~isempty(app.Player)
        stop(app.Player);
        app.Player = [];
    end

    % reset all pad buttons to gray
    padButtons = [app.PAD1Button app.PAD2Button app.PAD3Button ...
                  app.PAD4Button app.PAD5Button app.PAD6Button ...
                  app.PAD7Button app.PAD8Button app.PAD9Button];

    for i = 1:9
        padButtons(i).BackgroundColor = [0.18 0.18 0.18];

        % reset pause button text
    app.PAUSEButton.Text = "PAUSE";

    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PADSSAMPLESLOTSPanel = uipanel(app.UIFigure);
            app.PADSSAMPLESLOTSPanel.Title = 'PADS / SAMPLE SLOTS';
            app.PAD1Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD1Button.ButtonPushedFcn = createCallbackFcn(app, @PAD1ButtonPushed, true);
            app.PAD1Button.Text = 'PAD 1';
            app.PAD2Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD2Button.ButtonPushedFcn = createCallbackFcn(app, @PAD2ButtonPushed, true);
            app.PAD2Button.Text = 'PAD 2';
            app.PAD3Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD3Button.ButtonPushedFcn = createCallbackFcn(app, @PAD3ButtonPushed, true);
            app.PAD3Button.Text = 'PAD 3';
            app.PAD4Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD4Button.ButtonPushedFcn = createCallbackFcn(app, @PAD4ButtonPushed, true);
            app.PAD4Button.Text = 'PAD 4';
            app.PAD5Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD5Button.ButtonPushedFcn = createCallbackFcn(app, @PAD5ButtonPushed, true);
            app.PAD5Button.Text = 'PAD 5';
            app.PAD6Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD6Button.ButtonPushedFcn = createCallbackFcn(app, @PAD6ButtonPushed, true);
            app.PAD6Button.Text = 'PAD 6';
            app.PAD7Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD7Button.ButtonPushedFcn = createCallbackFcn(app, @PAD7ButtonPushed, true);
            app.PAD7Button.Text = 'PAD 7';
            app.PAD8Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD8Button.ButtonPushedFcn = createCallbackFcn(app, @PAD8ButtonPushed, true);
            app.PAD8Button.Text = 'PAD 8';
            app.PAD9Button = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.PAD9Button.ButtonPushedFcn = createCallbackFcn(app, @PAD9ButtonPushed, true);
            app.PAD9Button.Text = 'PAD 9';
            app.LOADButton = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.LOADButton_2 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_2.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_2Pushed, true);
            app.LOADButton_2.Text = 'LOAD';
            app.LOADButton_3 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_3.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_3Pushed, true);
            app.LOADButton_3.Text = 'LOAD';
            app.LOADButton_4 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_4.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_4Pushed, true);
            app.LOADButton_4.Text = 'LOAD';
            app.LOADButton_5 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_5.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_5Pushed, true);
            app.LOADButton_5.Text = 'LOAD';
            app.LOADButton_6 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_6.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_6Pushed, true);
            app.LOADButton_6.Text = 'LOAD';
            app.LOADButton_7 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_7.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_7Pushed, true);
            app.LOADButton_7.Text = 'LOAD';
            app.LOADButton_8 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_8.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_8Pushed, true);
            app.LOADButton_8.Text = 'LOAD';
            app.LOADButton_9 = uibutton(app.PADSSAMPLESLOTSPanel, 'push');
            app.LOADButton_9.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_9Pushed, true);
            app.LOADButton_9.Text = 'LOAD';
            app.AUDIOPROCESSINGPanel = uipanel(app.UIFigure);
            app.AUDIOPROCESSINGPanel.Title = 'AUDIO PROCESSING';
            app.Panel = uipanel(app.AUDIOPROCESSINGPanel);
            app.SlotLabel = uilabel(app.Panel);
            app.SlotLabel.Text = 'SLOT';
            app.SLOTDropDown = uidropdown(app.Panel);
            app.SLOTDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SLOTDropDown.Value = '1';
            app.StartLabel = uilabel(app.Panel);
            app.StartLabel.Text = 'START';
            app.STARTEditField = uieditfield(app.Panel, 'numeric');
            app.EndLabel = uilabel(app.Panel);
            app.EndLabel.Text = 'END';
            app.ENDEditField = uieditfield(app.Panel, 'numeric');
            app.APPLYButton = uibutton(app.Panel, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.CHOPLabel = uilabel(app.Panel);
            app.CHOPLabel.Text = 'CHOP';
            app.Panel_2 = uipanel(app.AUDIOPROCESSINGPanel);
            app.FEEDBACKKnobLabel = uilabel(app.Panel_2);
            app.FEEDBACKKnobLabel.Text = 'FEEDBACK';
            app.FEEDBACKKnob = uiknob(app.Panel_2, 'continuous');
            app.FEEDBACKKnob.Limits = [0 0.95];
            app.DELAYTIMEKnobLabel = uilabel(app.Panel_2);
            app.DELAYTIMEKnobLabel.Text = 'DELAY TIME';
            app.DELAYTIMEKnob = uiknob(app.Panel_2, 'continuous');
            app.DELAYTIMEKnob.Limits = [0 2];
            app.EchoSwitch = uiswitch(app.Panel_2, 'slider');
            app.ECHOLabel = uilabel(app.Panel_2);
            app.ECHOLabel.Text = 'ECHO';
            app.Panel_3 = uipanel(app.AUDIOPROCESSINGPanel);
            app.ReverbSwitch = uiswitch(app.Panel_3, 'slider');
            app.INTENSITYLabel = uilabel(app.Panel_3);
            app.INTENSITYLabel.Text = 'INTENSITY';
            app.INTENSITYSlider = uislider(app.Panel_3);
            app.INTENSITYSlider.Limits = [0 1];
            app.INTENSITYSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.REVERBLabel = uilabel(app.Panel_3);
            app.REVERBLabel.Text = 'REVERB';
            app.Panel_4 = uipanel(app.AUDIOPROCESSINGPanel);
            app.SPEEDSliderLabel = uilabel(app.Panel_4);
            app.SPEEDSliderLabel.Text = 'SPEED';
            app.SPEEDSlider = uislider(app.Panel_4);
            app.SPEEDSlider.Limits = [0.5 2];
            app.SPEEDSlider.MajorTicks = [0.5 1 1.5 2];
            app.SPEEDSlider.Value = 1;
            app.DIRECTIONSwitchLabel = uilabel(app.Panel_4);
            app.DIRECTIONSwitchLabel.Text = 'DIRECTION';
            app.DIRECTIONSwitch = uiswitch(app.Panel_4, 'slider');
            app.DIRECTIONSwitch.Items = {'Forward', 'Backward'};
            app.DIRECTIONSwitch.Value = 'Forward';
            app.VOLUMESliderLabel = uilabel(app.Panel_4);
            app.VOLUMESliderLabel.Text = 'VOLUME';
            app.VOLUMESlider = uislider(app.Panel_4);
            app.VOLUMESlider.Limits = [0 2];
            app.VOLUMESlider.MajorTicks = [0 0.5 1 1.5 2];
            app.VOLUMESlider.Value = 1;
            app.AUSTINPELLETIERLabel = uilabel(app.Panel_4);
            app.AUSTINPELLETIERLabel.Text = 'AUSTIN PELLETIER';
            app.SAMPLERV10Label = uilabel(app.Panel_4);
            app.SAMPLERV10Label.Text = 'SAMPLER V1.0';
            app.Panel_5 = uipanel(app.AUDIOPROCESSINGPanel);
            app.PAUSEButton = uibutton(app.Panel_5, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.Panel_5, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TONEGENERATORPanel = uipanel(app.UIFigure);
            app.TONEGENERATORPanel.Title = 'TONE GENERATOR';
            app.WAVEFORMDropDownLabel = uilabel(app.TONEGENERATORPanel);
            app.WAVEFORMDropDownLabel.Text = 'WAVEFORM';
            app.WAVEFORMDropDown = uidropdown(app.TONEGENERATORPanel);
            app.WAVEFORMDropDown.Items = {'Sine', 'Square', 'Saw', 'AM'};
            app.WAVEFORMDropDown.Value = 'Sine';
            app.FrequencyHzLabel = uilabel(app.TONEGENERATORPanel);
            app.FrequencyHzLabel.Text = 'FREQUENCY';
            app.FREQUENCYEditField = uieditfield(app.TONEGENERATORPanel, 'numeric');
            app.DURATIONEditFieldLabel = uilabel(app.TONEGENERATORPanel);
            app.DURATIONEditFieldLabel.Text = 'DURATION';
            app.DURATIONEditField = uieditfield(app.TONEGENERATORPanel, 'numeric');
            app.GENERATEButton = uibutton(app.TONEGENERATORPanel, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.SAVEButton = uibutton(app.TONEGENERATORPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.SYNTHLOADDropDownLabel = uilabel(app.TONEGENERATORPanel);
            app.SYNTHLOADDropDownLabel.Text = 'SYNTH LOAD';
            app.SYNTHLOADDropDown = uidropdown(app.TONEGENERATORPanel);
            app.SYNTHLOADDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.SYNTHLOADDropDown.Value = '1';
            app.EQUALIZERPanel = uipanel(app.UIFigure);
            app.EQUALIZERPanel.Title = 'EQUALIZER';
            app.HzSliderLabel = uilabel(app.EQUALIZERPanel);
            app.HzSliderLabel.Text = '63 Hz';
            app.HzSlider = uislider(app.EQUALIZERPanel);
            app.HzSlider.Limits = [-12 12];
            app.HzLabel = uilabel(app.EQUALIZERPanel);
            app.HzLabel.Text = '250 Hz';
            app.HzSlider_2 = uislider(app.EQUALIZERPanel);
            app.HzSlider_2.Limits = [-12 12];
            app.kHzSliderLabel = uilabel(app.EQUALIZERPanel);
            app.kHzSliderLabel.Text = '1k Hz';
            app.kHzSlider = uislider(app.EQUALIZERPanel);
            app.kHzSlider.Limits = [-12 12];
            app.kHzSlider_2Label = uilabel(app.EQUALIZERPanel);
            app.kHzSlider_2Label.Text = '4k Hz';
            app.kHzSlider_2 = uislider(app.EQUALIZERPanel);
            app.kHzSlider_2.Limits = [-12 12];
            app.kHzSlider_3Label = uilabel(app.EQUALIZERPanel);
            app.kHzSlider_3Label.Text = '8k Hz';
            app.kHzSlider_3 = uislider(app.EQUALIZERPanel);
            app.kHzSlider_3.Limits = [-12 12];
            app.Panel_6 = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Panel_6);
            title(app.UIAxes2, 'SYNTH')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.Panel_6);
            title(app.UIAxes, 'WAVEFORM')
            xlabel(app.UIAxes, 'TIME(S)')
            ylabel(app.UIAxes, 'AMPLITUDE')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioSampler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end